classdef free_exploration_and_quantization_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        LowHighLabel             matlab.ui.control.Label
        ConfidenceSlider         matlab.ui.control.Slider
        ConfidenceSliderLabel    matlab.ui.control.Label
        SubmitRatingButton       matlab.ui.control.Button
        SameLabel                matlab.ui.control.Label
        DifferentSlider          matlab.ui.control.Slider
        DifferentSliderLabel     matlab.ui.control.Label
        MatchEditableTexturetoReferenceTextureLabel  matlab.ui.control.Label
        EditableLabel            matlab.ui.control.Label
        ReferenceLabel           matlab.ui.control.Label
        PitchSlider              matlab.ui.control.Slider
        PitchSliderLabel         matlab.ui.control.Label
        IrregularitySlider       matlab.ui.control.Slider
        IrregularitySliderLabel  matlab.ui.control.Label
        V20Label                 matlab.ui.control.Label
        trialNum                 matlab.ui.control.Label
        TrialLabel               matlab.ui.control.Label
        submit                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        %%% CHANGE THE FOLLOWING STRING TO THE CORRECT COM PORT
        %%% TO CONNECT TO TPAD VIA USB:
        COMport = 'COM3';
        
        debug=0; % Debug mode flag

        tpadlength=19200;
        Location;
        
        dataload;
        dataload2;
        referenceTextures;
        expertPitches;
        data;
        port;
        postTest=false; %When 'true', trials switch to post-test
        textureTrial = 1; % Regular trials
        textureTrialMax = 9;
        postTestTrial = 1'% Post-test trials
        postTestTrialMax = 27;
        texelCount = 192;
        texelLength = 50;

        pitch; %editable pitch variable
        irregularity; %editable irregularity variable
        editableTexture; %Editable texture placeholder;
        editablePitches=zeros(9,192);
        
        irregularityScaling = 0.1;
        
        resultsLog=zeros(9,3);
        ratingsLog=zeros(27,1);

        compareOrder = [0 3 0;0 1 0;1 5 0.1;1 9 0;1 2 0.1;1 9 0.5;0 9 0;1 7 0;1 2 0;1 4 0;1 9 0.1;1 5 0.5;1 6 0;0 6 0;1 5 0.3;1 5 0;1 8 0;0 8 0;0 7 0;0 4 0;0 2 0;1 1 0;1 2 0.3;1 9 0.3;0 5 0;1 3 0;1 2 0.5]; % [Texture 1, Texture 2, Quantization] for each rating trial
        
        freqs;
        freqslog;
        ftex;
        pd;
        y;
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %% initialize connection to TPaD:
            [app.port,~] = connect2tpad(app.COMport);

            if(app.debug)
                for i=1:9600
                    app.freqs(i)=(i-1)*1/(9600*.0053);
                end
                app.freqslog=log10(app.freqs);
            end
            
            app.dataload = load('reference_textures.mat');
            app.dataload2 = load('trained_rater_results.mat');
            app.referenceTextures=app.dataload.reference_textures;
            app.expertPitches=app.dataload2.trained_rater_textures;

            app.pitch=10^(app.PitchSlider.Value);
            app.irregularity=app.IrregularitySlider.Value*app.irregularityScaling;
            
            [app.editableTexture,app.editablePitches(app.textureTrial,:),~]=buildFine5(app.texelCount,app.texelLength,app.pitch*ones(1,app.texelCount),app.irregularity*ones(1,app.texelCount),false,0);
                     
            loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.textureTrial,:)*2^16 ((app.editableTexture+1)/2)*2^16]);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            assignin('base','Values',app.resultsLog)
            assignin('base','Ratings',app.ratingsLog)
            app.Location = pwd; 
            temp = app.resultsLog; 
            t = datetime('now','Format','yyyy-MM-dd''_''HHmmss');
            s = char(t);
            save(fullfile(app.Location, ['Values ',s]), 'temp');
            temp = app.ratingsLog;
            save(fullfile(app.Location, ['Ratings ',s]), 'temp');
            delete(app);
            
        end

        % Button pushed function: submit
        function submitButtonPushed(app, event)
                       
            app.resultsLog(app.textureTrial,1)=10^(app.PitchSlider.Value);
            app.resultsLog(app.textureTrial,2)=app.IrregularitySlider.Value*app.irregularityScaling;
            app.resultsLog(app.textureTrial,3)=app.ConfidenceSlider.Value;
            
            app.textureTrial = app.textureTrial + 1;
            
            app.PitchSlider.Value=0.5;
            app.IrregularitySlider.Value=5;
            app.ConfidenceSlider.Value=3;


            if app.textureTrial > app.textureTrialMax %regular test over
                app.postTest=true;
                app.postTestTrial = 1;
                app.trialNum.Text = string(app.postTestTrial);
                
                app.submit.Visible=false;
                app.submit.Enable=false;
                app.IrregularitySlider.Visible=false;
                app.IrregularitySlider.Enable=false;
                app.IrregularitySliderLabel.Visible=false;
                app.PitchSlider.Visible=false;
                app.PitchSlider.Enable=false;
                app.PitchSliderLabel.Visible=false;
                app.ConfidenceSlider.Visible=false;
                app.ConfidenceSlider.Enable=false;
                app.ConfidenceSliderLabel.Visible=false;
                app.LowHighLabel.Visible=false;
                app.submit.Visible=false;
                app.submit.Enable=false;
                app.ReferenceLabel.Text="Texture 1";
                app.EditableLabel.Text="Texture 2";
                app.MatchEditableTexturetoReferenceTextureLabel.Text="Compare Texture 1 to Texture 2";
                app.SubmitRatingButton.Visible=true;
                app.SubmitRatingButton.Enable=true;
                app.DifferentSlider.Enable=true;
                app.DifferentSlider.Visible=true;
                app.DifferentSliderLabel.Visible=true;
                app.SameLabel.Visible=true;
                             
                if (app.compareOrder(app.postTestTrial,1)==0)
                    [app.editableTexture,~,~]=buildFine5(app.texelCount,app.texelLength,app.editablePitches(app.compareOrder(app.postTestTrial,2),:),0.0*ones(1,app.texelCount),false,app.compareOrder(app.postTestTrial,3));
                else
                    [app.editableTexture,~,~]=buildFine5(app.texelCount,app.texelLength,app.expertPitches(app.compareOrder(app.postTestTrial,2),:),0.0*ones(1,app.texelCount),false,app.compareOrder(app.postTestTrial,3));
                end
                     
                loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.compareOrder(app.postTestTrial,2),:)*2^16 ((app.editableTexture+1)/2)*2^16]);

            else %still more regular test trials
                app.trialNum.Text = string(app.textureTrial);
                
                app.pitch=10^(app.PitchSlider.Value);
                app.irregularity=app.IrregularitySlider.Value*app.irregularityScaling;
            
                [app.editableTexture,app.editablePitches(app.textureTrial,:),~]=buildFine5(app.texelCount,app.texelLength,app.pitch*ones(1,app.texelCount),app.irregularity*ones(1,app.texelCount),false,0);
                     
                loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.textureTrial,:)*2^16 ((app.editableTexture+1)/2)*2^16]);
            end
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            app.pitch=10^(app.PitchSlider.Value);
            app.irregularity=app.IrregularitySlider.Value*app.irregularityScaling;
            
            [app.editableTexture,app.editablePitches(app.textureTrial,:),~]=buildFine5(app.texelCount,app.texelLength,app.pitch*ones(1,app.texelCount),app.irregularity*ones(1,app.texelCount),false,0);
                     
            loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.textureTrial,:)*2^16 ((app.editableTexture+1)/2)*2^16]);

            if(app.debug)
                app.ftex=abs(fft(app.referenceTextures(app.textureTrial,:)));
                app.ftex(1)=0;
                app.ftex(9600)=0;
                app.ftex=app.ftex/max(app.ftex);

                app.pd=makedist('Normal',log10(app.pitch),app.irregularity);
                app.y=pdf(app.pd,app.freqslog);
                app.y=app.y/max(app.y);

                figure(1)
                hold off
                plot(app.freqslog,app.ftex,'LineWidth',2)
                hold on
                plot(app.freqslog,app.y)
                xlim([-1,2])
                xline(log10(app.pitch),'--')
            end
        end

        % Value changed function: IrregularitySlider
        function IrregularitySliderValueChanged(app, event)
            app.pitch=10^(app.PitchSlider.Value);
            app.irregularity=app.IrregularitySlider.Value*app.irregularityScaling;
            
            [app.editableTexture,app.editablePitches(app.textureTrial,:),~]=buildFine5(app.texelCount,app.texelLength,app.pitch*ones(1,app.texelCount),app.irregularity*ones(1,app.texelCount),false,0);
                     
            loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.textureTrial,:)*2^16 ((app.editableTexture+1)/2)*2^16]);
            
            if(app.debug)
                app.ftex=abs(fft(app.referenceTextures(app.textureTrial,:)));
                app.ftex(1)=0;
                app.ftex(9600)=0;
                app.ftex=app.ftex/max(app.ftex);

                app.pd=makedist('Normal',log10(app.pitch),app.irregularity);
                app.y=pdf(app.pd,app.freqslog);
                app.y=app.y/max(app.y);

                figure(1)
                hold off
                plot(app.freqslog,app.ftex,'LineWidth',2)
                hold on
                plot(app.freqslog,app.y)
                xlim([-1,2])
                xline(log10(app.pitch),'--')
            end
        end

        % Button pushed function: SubmitRatingButton
        function SubmitRatingButtonPushed(app, event)
            app.ratingsLog(app.postTestTrial,1)=app.DifferentSlider.Value;
            
            app.postTestTrial = app.postTestTrial + 1;
            
            app.DifferentSlider.Value=3;
            
            if app.postTestTrial > app.postTestTrialMax
                app.MatchEditableTexturetoReferenceTextureLabel.Text="Test Complete!";
                app.ReferenceLabel.Visible=false;
                app.EditableLabel.Visible=false;
                app.DifferentSlider.Visible=false;
                app.DifferentSlider.Enable=false;
                app.SubmitRatingButton.Visible=false;
                app.SubmitRatingButton.Enable=false;
                
                assignin('base','Values',app.resultsLog)
                assignin('base','Ratings',app.ratingsLog)
                app.Location = pwd; 
                temp = app.resultsLog; 
                t = datetime('now','Format','yyyy-MM-dd''_''HHmmss');
                s = char(t);
                save(fullfile(app.Location, ['Values ',s]), 'temp');
                temp = app.ratingsLog;
                save(fullfile(app.Location, ['Ratings ',s]), 'temp');
            else %More post-test trials
                app.trialNum.Text=string(app.postTestTrial);
                
                if (app.compareOrder(app.postTestTrial,1)==0)
                    [app.editableTexture,~,~]=buildFine5(app.texelCount,app.texelLength,app.editablePitches(app.compareOrder(app.postTestTrial,2),:),0.0*ones(1,app.texelCount),false,app.compareOrder(app.postTestTrial,3));
                else
                    [app.editableTexture,~,~]=buildFine5(app.texelCount,app.texelLength,app.expertPitches(app.compareOrder(app.postTestTrial,2),:),0.0*ones(1,app.texelCount),false,app.compareOrder(app.postTestTrial,3));
                end
                     
                loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.compareOrder(app.postTestTrial,2),:)*2^16 ((app.editableTexture+1)/2)*2^16]);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.submit = uibutton(app.UIFigure, 'push');
            app.submit.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submit.Text = 'Submit';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial ';
            app.trialNum = uilabel(app.UIFigure);
            app.trialNum.Text = '1';
            app.V20Label = uilabel(app.UIFigure);
            app.V20Label.Text = 'V 2.0';
            app.IrregularitySliderLabel = uilabel(app.UIFigure);
            app.IrregularitySliderLabel.Text = 'Irregularity';
            app.IrregularitySlider = uislider(app.UIFigure);
            app.IrregularitySlider.Limits = [0 10];
            app.IrregularitySlider.ValueChangedFcn = createCallbackFcn(app, @IrregularitySliderValueChanged, true);
            app.IrregularitySlider.MinorTicks = [];
            app.IrregularitySlider.Value = 5;
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.Limits = [-1 2];
            app.PitchSlider.MajorTicks = [-1 -0.7 -0.4 -0.1 0.2 0.5 0.8 1.1 1.4 1.7 2];
            app.PitchSlider.MajorTickLabels = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.PitchSlider.MinorTicks = [];
            app.PitchSlider.Value = 0.5;
            app.ReferenceLabel = uilabel(app.UIFigure);
            app.ReferenceLabel.Text = 'Reference';
            app.EditableLabel = uilabel(app.UIFigure);
            app.EditableLabel.Text = 'Editable';
            app.MatchEditableTexturetoReferenceTextureLabel = uilabel(app.UIFigure);
            app.MatchEditableTexturetoReferenceTextureLabel.Text = 'Match Editable Texture to Reference Texture';
            app.DifferentSliderLabel = uilabel(app.UIFigure);
            app.DifferentSliderLabel.Enable = 'off';
            app.DifferentSliderLabel.Visible = 'off';
            app.DifferentSliderLabel.Text = 'Different';
            app.DifferentSlider = uislider(app.UIFigure);
            app.DifferentSlider.Limits = [1 5];
            app.DifferentSlider.MajorTicks = [1 2 3 4 5];
            app.DifferentSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.DifferentSlider.MinorTicks = [];
            app.DifferentSlider.Enable = 'off';
            app.DifferentSlider.Visible = 'off';
            app.DifferentSlider.Value = 3;
            app.SameLabel = uilabel(app.UIFigure);
            app.SameLabel.Visible = 'off';
            app.SameLabel.Text = 'Same';
            app.SubmitRatingButton = uibutton(app.UIFigure, 'push');
            app.SubmitRatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitRatingButtonPushed, true);
            app.SubmitRatingButton.Enable = 'off';
            app.SubmitRatingButton.Visible = 'off';
            app.SubmitRatingButton.Text = 'Submit Rating';
            app.ConfidenceSliderLabel = uilabel(app.UIFigure);
            app.ConfidenceSliderLabel.Text = 'Confidence';
            app.ConfidenceSlider = uislider(app.UIFigure);
            app.ConfidenceSlider.Limits = [1 5];
            app.ConfidenceSlider.MajorTicks = [1 2 3 4 5];
            app.ConfidenceSlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.ConfidenceSlider.MinorTicks = [];
            app.ConfidenceSlider.Value = 3;
            app.LowHighLabel = uilabel(app.UIFigure);
            app.LowHighLabel.Text = 'Low                                               High';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = free_exploration_and_quantization_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end