classdef guided_exploration_test_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        submitRating           matlab.ui.control.Button
        ratingLabel            matlab.ui.control.Label
        texNumLabel            matlab.ui.control.Label
        TexLabel               matlab.ui.control.Label
        choice                 matlab.ui.container.ButtonGroup
        unsure                 matlab.ui.control.RadioButton
        texB                   matlab.ui.control.RadioButton
        texA                   matlab.ui.control.RadioButton
        texBbutton             matlab.ui.control.Button
        texAbutton             matlab.ui.control.Button
        refbutton              matlab.ui.control.Button
        DifferentSameLabel     matlab.ui.control.Label
        SimilaritySlider       matlab.ui.control.Slider
        SimilaritySliderLabel  matlab.ui.control.Label
        V10Label               matlab.ui.control.Label
        trialNum               matlab.ui.control.Label
        TrialLabel             matlab.ui.control.Label
        submit                 matlab.ui.control.Button
    end

    %Purpose of this software is to guide users through matching reference
    %textures to texel renderings
    %Subspace is given by ordered pair: (pitch,irregularity)

    properties (Access = private)
        
        %%% CHANGE THE FOLLOWING STRING TO THE COM PORT
        %%% TO CONNECT TO TPAD VIA USB:
        COMport = 'COM3';
        
        debug=0; %If 1, display debug plots
        useTPad=1; %If 0, run test without TPad functions

        tpadlength=19200;
        Location;
        
        dataload;
        dataload2;
        referenceTextures;
        refTex;
        refPitch;
        data;
        port;

        texNum = 1; %Current reference texture under investigation
        trial = 1; %overall trial within one texture
        subtrial=1; %subtrial within one subspace
        ratingtrial=1; %rating trial (out of 9)
        
        texelCount = 384;
        texelLength = 50;


        centerstart=[0.5,0.5]; %start point for center of subspace
        edgestart=1.0; %starting edge length
        shrinkAmount = 0.2; %Absolute amount (0.0-1.0) that subspace shrinks each trial

        center; %current center of subspace
        edge; %current edge width of subspace
        pursuit=[0;0]; %pursuit vector
        pursuitMax=1+2/sqrt(2); %Maximum length of pursuit vector

        pitchA; %editable pitch variable: texture A
        irregA; %editable irregularity variable: texture A
        pitchB; %editable pitch variable: texture B
        irregB; %editable irregularity variable: texture B
        textureA; %Texture A placeholder
        textureB; %Texture B placeholder
        texReverse = 1; %1 or -1, used to switch textures between A and B
        coinFlip;

        comparisonOrder=[1,2,3,4]; %Order that comparisons are made each trial. (Randomized)
        comparisonScale=0.5; %How far along the vector one point of the comparison is from the center
        comparisons1=[0,1;1/sqrt(2),1/sqrt(2);1,0;1/sqrt(2),-1/sqrt(2)];
        comparisons2=[0,-1;-1/sqrt(2),-1/sqrt(2);-1,0;-1/sqrt(2),1/sqrt(2)];
        comparisonResults=[0,0,0,0]; %For each trial, store the subtrial results as -1: comparison2, 1:comparison1, 0:unknown


        %Pitch limits: 10^-1.5, 10^2.5
        pitchScaling=4;
        pitchOffset=-1.5;
        
        %Irregularity limits: 10^-2.5, 10^0.5 
        irregularityScaling = 3;
        irregularityOffset = -2.5;
        
        resultsLog=zeros(3,5,2);
        ratingsLog=zeros(3,3);
        ratingorder=[3,1;2,1;1,1;
                        3,2;2,2;1,2;
                        3,3;2,3;1,3];

        freqs;
        freqslog;
        ftex;
        pd;
        y;
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %% initialize connection to TPaD:
            if(app.useTPad)
                [app.port,~] = connect2tpad(app.COMport);
            end
            
            app.dataload = load('reference_textures.mat');
            app.referenceTextures=app.dataload.reference_textures;
            app.dataload2 = load('trained_rater_results.mat');
            app.refTex=app.dataload2.trained_rater_output;
            app.refPitch=app.dataload2.trained_rater_textures;

            %First comparison
            app.center=app.centerstart;
            app.edge=app.edgestart;
            app.comparisonOrder=randperm(4);
            app.coinFlip=randi(2);
            if (app.coinFlip==1)
                app.texReverse = 1;
            else
                app.texReverse = -1;
            end

            app.pitchA=10^((app.center(1)+(app.comparisons1(app.comparisonOrder(app.subtrial),1)*(app.edge/2)*app.comparisonScale))*app.pitchScaling+app.pitchOffset);
            app.irregA=10^((app.center(2)+(app.comparisons1(app.comparisonOrder(app.subtrial),2)*(app.edge/2)*app.comparisonScale))*app.irregularityScaling+app.irregularityOffset);

            app.pitchB=10^((app.center(1)+(app.comparisons2(app.comparisonOrder(app.subtrial),1)*(app.edge/2)*app.comparisonScale))*app.pitchScaling+app.pitchOffset);
            app.irregB=10^((app.center(2)+(app.comparisons2(app.comparisonOrder(app.subtrial),2)*(app.edge/2)*app.comparisonScale))*app.irregularityScaling+app.irregularityOffset);

            if(app.debug)
                if(app.texReverse==1)
                    scatter([app.pitchA],[app.irregA],[],'filled')
                    hold on
                    scatter([app.pitchB],[app.irregB],[],'filled')
                else
                    scatter([app.pitchB],[app.irregB],[],'filled')
                    hold on
                    scatter([app.pitchA],[app.irregA],[],'filled')
                end
                set(gca,'xscale','log')
                set(gca,'yscale','log')
                xlim([10^-2 10^3])
                ylim([10^-3 10^1])
                hold off
            end
            
            [app.textureA,~,~]=buildFine5(app.texelCount,app.texelLength,app.pitchA*ones(1,app.texelCount),app.irregA*ones(1,app.texelCount),false,0);
            [app.textureB,~,~]=buildFine5(app.texelCount,app.texelLength,app.pitchB*ones(1,app.texelCount),app.irregB*ones(1,app.texelCount),false,0);
             
            if(app.useTPad)
                loadData(app.port,0,zeros(app.tpadlength,1),zeros(1,app.tpadlength));
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            assignin('base','centers',app.resultsLog)
            assignin('base','ratings',app.ratingsLog)
            app.Location = pwd; 
            centers = app.resultsLog; 
            t = datetime('now','Format','yyyy-MM-dd''_''HHmmss');
            s = char(t);
            save(fullfile(app.Location, ['guidedResults ',s]), 'centers');
            ratings=app.ratingsLog;
            save(fullfile(app.Location, ['guidedRatings ',s]), 'ratings');
            delete(app);
            
        end

        % Button pushed function: submit
        function submitButtonPushed(app, event)
            %Clear button status
            app.refbutton.BackgroundColor=[0.96 0.96 0.96];
            app.refbutton.Enable=true;
            app.texAbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texAbutton.Enable=true;
            app.texBbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texBbutton.Enable=true;

            %Clear TPad screen
            if(app.useTPad)
                loadData(app.port,0,zeros(app.tpadlength,1),zeros(1,app.tpadlength));
            end

            %Record comparison results for current subtrial
            if(app.texA.Value)
                app.comparisonResults(app.comparisonOrder(app.subtrial))=1*app.texReverse;
            elseif(app.texB.Value)
                app.comparisonResults(app.comparisonOrder(app.subtrial))=-1*app.texReverse;
            elseif(app.unsure.Value)
                app.comparisonResults(app.comparisonOrder(app.subtrial))=0;
            end
            
            if(app.subtrial==4) %Trial has finished

                %Compute pursuit vector
                app.pursuit=[0,0];
                for ii=1:4
                    app.pursuit=app.pursuit + app.comparisonResults(ii)*app.comparisons1(ii,:);
                end
                if(app.debug) %DEBUG: Plot pursuit vector
                    plot([0,app.pursuit(1)],[0,app.pursuit(2)])
                    xlim([-3,3])
                    ylim([-3,3])
                end

                %Move center and shrink subspace
                app.center=app.center+(app.pursuit*(app.shrinkAmount/2)/app.pursuitMax);
                app.edge=app.edge-app.shrinkAmount;

                %Record moved center in results log
                app.resultsLog(app.texNum,app.trial,:)=app.center;
                
                %Increment trial, return to subtrial 1
                app.subtrial=1;
                app.trial=app.trial+1;

                %Randomize comparison order
                app.comparisonOrder=randperm(4);

            else % Trial not finished

                app.subtrial=app.subtrial+1;

            end

            if (app.edge<0.15) % Result value for this texture has been obtained, start next texture
                app.texNum=app.texNum+1;
                app.trial=1;
                app.subtrial=1;
                app.texNumLabel.Text=num2str(app.texNum);
                app.center=app.centerstart;
                app.edge=app.edgestart;
            end


            %Randomize textures A and B
            app.coinFlip=randi(2);
            if (app.coinFlip==1)
                app.texReverse = 1;
            else
                app.texReverse = -1;
            end

            %Compute pitch and irregularity for comparision choices
            app.pitchA=10^((app.center(1)+(app.comparisons1(app.comparisonOrder(app.subtrial),1)*(app.edge/2)*app.comparisonScale))*app.pitchScaling+app.pitchOffset);
            app.irregA=10^((app.center(2)+(app.comparisons1(app.comparisonOrder(app.subtrial),2)*(app.edge/2)*app.comparisonScale))*app.irregularityScaling+app.irregularityOffset);

            app.pitchB=10^((app.center(1)+(app.comparisons2(app.comparisonOrder(app.subtrial),1)*(app.edge/2)*app.comparisonScale))*app.pitchScaling+app.pitchOffset);
            app.irregB=10^((app.center(2)+(app.comparisons2(app.comparisonOrder(app.subtrial),2)*(app.edge/2)*app.comparisonScale))*app.irregularityScaling+app.irregularityOffset);

            %DEBUG: Plot comparison choices and display current edge length
            if(app.debug)
                if(app.texReverse==1)
                    scatter([app.pitchA],[app.irregA],[],'filled')
                    hold on
                    scatter([app.pitchB],[app.irregB],[],'filled')
                else
                    scatter([app.pitchB],[app.irregB],[],'filled')
                    hold on
                    scatter([app.pitchA],[app.irregA],[],'filled')
                end
                set(gca,'xscale','log')
                set(gca,'yscale','log')
                xlim([10^-2 10^3])
                ylim([10^-3 10^1])
                hold off

                app.V10Label.Text=num2str(app.edge);
            end

            %Render texture choices
            [app.textureA,~,~]=buildFine5(app.texelCount,app.texelLength,app.pitchA*ones(1,app.texelCount),app.irregA*ones(1,app.texelCount),false,0);
            [app.textureB,~,~]=buildFine5(app.texelCount,app.texelLength,app.pitchB*ones(1,app.texelCount),app.irregB*ones(1,app.texelCount),false,0);

            %Reset decision control and confidence slider
            app.unsure.Value=true;
            app.texA.Value=false;
            app.texB.Value=false;
            app.SimilaritySlider.Value=3;

            %Display current trial (36 max per texture)
            app.trialNum.Text=num2str(app.subtrial+(app.trial-1)*4);

            if (app.texNum>3.5) %All textures have been tested, start ratings trials

                    app.unsure.Enable=false;
                    app.texB.Enable=false;
                    app.texA.Enable=false;
                    app.choice.Visible=false;
                    app.TrialLabel.Visible=false;
                    app.trialNum.Text="Rating " + num2str(app.ratingtrial) +"/9";
                    app.refbutton.Enable=false;
                    app.refbutton.Visible=false;
                    app.submit.Enable=false;
                    app.submit.Visible=false;
                    app.submitRating.Enable=true;
                    app.submitRating.Visible=true;
                    app.texNumLabel.Visible=false;
                    app.TexLabel.Visible=false;
                    app.ratingLabel.Visible=true;
                    app.DifferentSameLabel.Visible=true;
                    app.SimilaritySlider.Visible=true;
                    app.SimilaritySlider.Enable=true;
                    app.SimilaritySlider.Value=3;
                    app.pitchA=10^app.resultsLog(app.ratingorder(app.ratingtrial,1),5,1);
                    app.irregA=10^app.resultsLog(app.ratingorder(app.ratingtrial,1),5,2);
                    [app.textureA,~,~]=buildFine5(app.texelCount,app.texelLength,[app.refPitch(app.ratingorder(app.ratingtrial,1),:) app.refPitch(app.ratingorder(app.ratingtrial,1),:)],zeros(1,app.texelCount),false,0);
                    app.textureB=([app.referenceTextures(app.ratingorder(app.ratingtrial,2),:) app.referenceTextures(app.ratingorder(app.ratingtrial,2),:)]*2)-1;  
                    app.texReverse=1;

              
            end
                    
        end

        % Button pushed function: texAbutton
        function texAbuttonPushed(app, event)
            app.refbutton.BackgroundColor=[0.96 0.96 0.96];
            app.refbutton.Enable=true;
            app.texBbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texBbutton.Enable=true;

            if(app.useTPad)
                if(app.texReverse==1)
                    loadData(app.port,0,zeros(app.tpadlength,1),((app.textureA+1)/2)*2^16);
                else
                    loadData(app.port,0,zeros(app.tpadlength,1),((app.textureB+1)/2)*2^16);
                end
            end

            app.texAbutton.BackgroundColor=[1.0 1.0 0.7];
            app.texAbutton.Enable=false;
        end

        % Button pushed function: texBbutton
        function texBbuttonPushed(app, event)
            app.refbutton.BackgroundColor=[0.96 0.96 0.96];
            app.refbutton.Enable=true;
            app.texAbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texAbutton.Enable=true;
            
            if(app.useTPad)
                if(app.texReverse==1)
                    loadData(app.port,0,zeros(app.tpadlength,1),((app.textureB+1)/2)*2^16);
                else
                    loadData(app.port,0,zeros(app.tpadlength,1),((app.textureA+1)/2)*2^16);
                end
            end

            app.texBbutton.BackgroundColor=[1.0 1.0 0.7];
            app.texBbutton.Enable=false;
        end

        % Button pushed function: refbutton
        function refbuttonPushed(app, event)
            app.texAbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texAbutton.Enable=true;
            app.texBbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texBbutton.Enable=true;
            
            if(app.useTPad)
                loadData(app.port,0,zeros(app.tpadlength,1),[app.referenceTextures(app.texNum,:)*2^16 app.referenceTextures(app.texNum,:)*2^16]);
            end

            app.refbutton.BackgroundColor=[1.0 1.0 0.7];
            app.refbutton.Enable=false;
        end

        % Button pushed function: submitRating
        function submitRatingButtonPushed(app, event)
            app.texAbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texAbutton.Enable=true;
            app.texBbutton.BackgroundColor=[0.96 0.96 0.96];
            app.texBbutton.Enable=true;
            
            app.ratingsLog(app.ratingorder(app.ratingtrial,1),app.ratingorder(app.ratingtrial,2))=app.SimilaritySlider.Value;
            app.ratingtrial=app.ratingtrial+1;
            app.trialNum.Text="Rating " + num2str(app.ratingtrial) +"/9";
            if(app.ratingtrial<9.5) %Rating trials continue
                app.SimilaritySlider.Value=3;
                app.pitchA=10^app.resultsLog(app.ratingorder(app.ratingtrial,1),5,1);
                app.irregA=10^app.resultsLog(app.ratingorder(app.ratingtrial,1),5,2);
                [app.textureA,~,~]=buildFine5(app.texelCount,app.texelLength,[app.refPitch(app.ratingorder(app.ratingtrial,1),:) app.refPitch(app.ratingorder(app.ratingtrial,1),:)],zeros(1,app.texelCount),false,0);
                app.textureB=([app.referenceTextures(app.ratingorder(app.ratingtrial,2),:) app.referenceTextures(app.ratingorder(app.ratingtrial,2),:)]*2)-1;  
            else %Rating trials endedN
                app.SimilaritySlider.Enable=false;
                app.SimilaritySlider.Visible=false;
                app.DifferentSameLabel.Visible=false;
                app.trialNum.Text="TEST COMPLETE";
                app.submitRating.Enable=false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.submit = uibutton(app.UIFigure, 'push');
            app.submit.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submit.Text = 'Submit';
            app.TrialLabel = uilabel(app.UIFigure);
            app.TrialLabel.Text = 'Trial ';
            app.trialNum = uilabel(app.UIFigure);
            app.trialNum.Text = '1';
            app.V10Label = uilabel(app.UIFigure);
            app.V10Label.Text = 'V 1.0';
            app.SimilaritySliderLabel = uilabel(app.UIFigure);
            app.SimilaritySliderLabel.Enable = 'off';
            app.SimilaritySliderLabel.Visible = 'off';
            app.SimilaritySliderLabel.Text = 'Similarity';
            app.SimilaritySlider = uislider(app.UIFigure);
            app.SimilaritySlider.Limits = [1 5];
            app.SimilaritySlider.MajorTicks = [1 2 3 4 5];
            app.SimilaritySlider.MajorTickLabels = {'1', '2', '3', '4', '5'};
            app.SimilaritySlider.MinorTicks = [];
            app.SimilaritySlider.Enable = 'off';
            app.SimilaritySlider.Visible = 'off';
            app.SimilaritySlider.Value = 3;
            app.DifferentSameLabel = uilabel(app.UIFigure);
            app.DifferentSameLabel.Enable = 'off';
            app.DifferentSameLabel.Visible = 'off';
            app.DifferentSameLabel.Text = 'Different                                              Same';
            app.refbutton = uibutton(app.UIFigure, 'push');
            app.refbutton.ButtonPushedFcn = createCallbackFcn(app, @refbuttonPushed, true);
            app.refbutton.Text = 'Reference';
            app.texAbutton = uibutton(app.UIFigure, 'push');
            app.texAbutton.ButtonPushedFcn = createCallbackFcn(app, @texAbuttonPushed, true);
            app.texAbutton.Text = 'Texture A';
            app.texBbutton = uibutton(app.UIFigure, 'push');
            app.texBbutton.ButtonPushedFcn = createCallbackFcn(app, @texBbuttonPushed, true);
            app.texBbutton.Text = 'Texture B';
            app.choice = uibuttongroup(app.UIFigure);
            app.choice.Title = 'Which texture feels more similar to the reference?';
            app.texA = uiradiobutton(app.choice);
            app.texA.Text = 'Texture A';
            app.texB = uiradiobutton(app.choice);
            app.texB.Text = 'Texture B';
            app.unsure = uiradiobutton(app.choice);
            app.unsure.Text = 'Unsure';
            app.unsure.Value = true;
            app.TexLabel = uilabel(app.UIFigure);
            app.TexLabel.Text = 'Tex';
            app.texNumLabel = uilabel(app.UIFigure);
            app.texNumLabel.Text = '1';
            app.ratingLabel = uilabel(app.UIFigure);
            app.ratingLabel.Visible = 'off';
            app.ratingLabel.Text = 'How similar do texures A and B feel?';
            app.submitRating = uibutton(app.UIFigure, 'push');
            app.submitRating.ButtonPushedFcn = createCallbackFcn(app, @submitRatingButtonPushed, true);
            app.submitRating.Enable = 'off';
            app.submitRating.Visible = 'off';
            app.submitRating.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = guided_exploration_test_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end